classdef interfacedesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SignalAnalysisTab               matlab.ui.container.Tab
        ReferenceelectrodeSpinner       matlab.ui.control.Spinner
        ReferenceelectrodeSpinnerLabel  matlab.ui.control.Label
        Button29                        matlab.ui.control.Button
        Button30                        matlab.ui.control.Button
        Button31                        matlab.ui.control.Button
        Button27                        matlab.ui.control.Button
        Button28                        matlab.ui.control.Button
        Button26                        matlab.ui.control.Button
        Button24                        matlab.ui.control.Button
        Button25                        matlab.ui.control.Button
        Button8                         matlab.ui.control.Button
        Button7                         matlab.ui.control.Button
        Button6                         matlab.ui.control.Button
        Button5                         matlab.ui.control.Button
        Button4                         matlab.ui.control.Button
        Button3                         matlab.ui.control.Button
        Button2                         matlab.ui.control.Button
        Button1                         matlab.ui.control.Button
        Button0                         matlab.ui.control.Button
        ViewallButton                   matlab.ui.control.Button
        Button0_7                       matlab.ui.control.Button
        Button8_15                      matlab.ui.control.Button
        Button16_23                     matlab.ui.control.Button
        Button24_31                     matlab.ui.control.Button
        ModesButtonGroup                matlab.ui.container.ButtonGroup
        ReferenceCheckBox               matlab.ui.control.CheckBox
        NotchBandpasshighfqButton       matlab.ui.control.RadioButton
        BandpasshighfqButton            matlab.ui.control.RadioButton
        NotchBandpasslowfqButton        matlab.ui.control.RadioButton
        BandpasslowfqButton             matlab.ui.control.RadioButton
        Notch                           matlab.ui.control.RadioButton
        Button9                         matlab.ui.control.Button
        Button10                        matlab.ui.control.Button
        Button11                        matlab.ui.control.Button
        Button12                        matlab.ui.control.Button
        Button13                        matlab.ui.control.Button
        Button14                        matlab.ui.control.Button
        Button15                        matlab.ui.control.Button
        Button16                        matlab.ui.control.Button
        Button17                        matlab.ui.control.Button
        Button18                        matlab.ui.control.Button
        Button23                        matlab.ui.control.Button
        Button19                        matlab.ui.control.Button
        Button20                        matlab.ui.control.Button
        Button22                        matlab.ui.control.Button
        Button21                        matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        KinematicanalysisTab            matlab.ui.container.Tab
        ElectrodeSpinner                matlab.ui.control.Spinner
        ElectrodeSpinnerLabel           matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        LoadvideofileButton             matlab.ui.control.Button
        TimeSpinner                     matlab.ui.control.Spinner
        TimeSpinnerLabel                matlab.ui.control.Label
        StartSpinner                    matlab.ui.control.Spinner
        StartSpinnerLabel               matlab.ui.control.Label
        fpsSpinner                      matlab.ui.control.Spinner
        fpsSpinnerLabel                 matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Property % assigned variables

        % Parameters

        % fn = fs/2;     % Nyquist freq
        % f0 = 6;        % order
        f_notch = 50;   % notch filter freq
        fs = 30000;     % sampling freq
        f1 = 1;         % lower threshold of low freq bp filter
        f2 = 100;       % upper threshold of low freq bp filter
        f3 = 50;        % lower threshold of high freq bp filter
        f4 = 4000;      % lower threshold of high freq bp filter

        % Load variables from main script
        amplifier_data = evalin('base', 'amplifier_data');
        t = evalin('base', 't');

        % GUI properties
        reference_electrode = NaN;

        % Video variables
        % stopvideo = true;

        % VideoFileName
        filename;
        % pathname;
        % get;
    end


    methods (Access = private)

        function electrode = channel_to_electrode(app, channel)
            % Get the number of the electrode(s) corresponding to the given
            % channel(s).
            %
            % Params
            % - channel: the number(s) of the channel(s). Can be a single 
            % integer or a list of integers.
            %
            % Return
            % - the corresponding electrode number(s). Either a single
            % integer, or a list, depending on shape of channel.
            profile on;

            electrode = zeros(size(channel));
            map = [0, 2, 4, 6, 8, 10, 12, 14, 16, 18, 20, 22, 24, 26, 28, 30, 31, 29, 27, 25, 23, 21, 19, 17, 15, 13, 11, 9, 7, 5, 3, 1];

            for i=1:length(channel)
                electrode(i) = map(channel(i));
            end
        end


        function channel = electrode_to_channel(app, electrode)
            % Get the number of the channel(s) corresponding to the given
            % electrode(s).
            %
            % Params
            % - electrode: the number(s) of the electrode(s). Can be a
            % single integer or a list of integers.
            %
            % Return
            % - the corresponding channel number(s). Either a single
            % integer, or a list, depending on shape of electrode.

            channel = zeros(size(electrode));
            map = [1, 32, 2, 31, 3, 30, 4, 29, 5, 28, 6, 27, 7, 26, 8, 25, 9, 24, 10, 23, 11, 22, 12, 21, 13, 20, 14, 19, 15, 18, 16, 17];

            for i=1:length(electrode)
                channel(i) = map(electrode(i)+1);
            end
        end


        function output = remove_reference_signal(app, signal)
            % Subtracts the reference spike data (from electrode
            % 'app.reference_electrode' from the desired signal, whose data
            % is passed into the function (provided the appropriate
            % checkbox has been selected in the GUI.
            %
            % Params
            % - signal: the data from the electrode to be measured.
            %
            % Return
            % - the data from the desired electrode, subtract the data from
            % the reference electrode, if the checkbox has been selected.
            % Otherwise, just the original signal.

            output = zeros(size(signal));

            if isnan(app.reference_electrode)
                output = signal;
            else
                ref_channel = app.electrode_to_channel(app.reference_electrode);
                for i=1:length(signal(:,1))
                    output(i, 1:end) = signal(i, 1:end) - app.amplifier_data(ref_channel, 1:end);
                end
            end
        end


        function filtered_data = filter_signal(app, data)
            % Filters the data based on the mode selected.
            %
            % Params
            % - data: the data to be filtered.
            %
            % Return
            % - the data after the filter has been applied.

            if app.Notch.Value
                filtered_data = filter_notch(data, app.f_notch, app.fs);

            elseif app.BandpasslowfqButton.Value
                filtered_data = filter_bandpass(data, app.f1, app.f2, app.fs);

            elseif app.NotchBandpasslowfqButton.Value
                data_notch = filter_notch(data, app.f_notch, app.fs);
                filtered_data = filter_bandpass(data_notch, app.f1, app.f2, app.fs);

            elseif app.BandpasshighfqButton.Value
                filtered_data = filter_bandpass(data, app.f3, app.f4, app.fs);

            elseif app.NotchBandpasshighfqButton.Value
                data_notch = filter_notch(data, app.f_notch, app.fs);
                filtered_data = filter_bandpass(data_notch, app.f3, app.f4, app.fs);
            end
        end


        function filtered_data = process_signal(app, channel_list)
            % Calls functions to remove the reference signal, and to filter
            % the data
            %
            % Params
            % - channel_list: one or more channels whose data is to be
            % processed.
            %
            % Return
            % - the data after removing the reference and filtering it.

            len = length(channel_list);
            unfiltered_data = zeros(len, length(app.amplifier_data(1, 1:end)));
            filtered_data = zeros(len, length(app.amplifier_data(1, 1:end)));

            for i=1:len
                channel = channel_list(i);
                unfiltered_data(i, 1:end) = app.remove_reference_signal(app.amplifier_data(channel, 1:end));
                filtered_data(i, 1:end) = app.filter_signal(unfiltered_data(i, 1:end));
            end
        end


        function single_electrode_button(app, electrode)
            % Process the data from a single electrode and plot the output
            % in pop-out window.
            %
            % Params
            % - electrode: the number of the electrode whose data is to be
            % displayed.

            channel = app.electrode_to_channel(electrode);
            single_channel_data = app.process_signal(channel);

            % Plotting
            figure; hold on;
            tiledlayout (1,1);
            plot(app.t(1:end), single_channel_data(1:end));
            title(sprintf('Electrode %d', electrode));
            xlabel('time (s)');
            ylabel('p.d.(mV)');
            profile viewer;
        end


        function video_electrode_button(app, electrode)
            % Process the data from a single electrode and plot the output
            % on axes below the video.
            %
            % Params
            % - electrode: the number of the electrode whose data is to be
            % displayed.

            channel = app.electrode_to_channel(electrode);
            video_single_data = app.process_signal(channel);

            % Plotting
            plot(app.UIAxes2, app.t(1:end), video_single_data(1:end));
            title(app.UIAxes2, (sprintf('Electrode %d', electrode)));
            xlabel(app.UIAxes2,'time (s)');
            ylabel(app.UIAxes2, 'p.d.(mV)');

        end


        function multiple_electrode_button(app, electrode_list)
            % Process the data from a list of electrodes and plot the
            % outputs in two columns in a pop-out window.
            %
            % Params
            % - electrode: a list of numbers of the electrodes whose data
            % is to be displayed.

            channel_list = app.electrode_to_channel(electrode_list);
            multiple_channel_data = app.process_signal(channel_list);

            % Plotting
            figure; hold on;
            tiledlayout(4, 2)

            for i=1:length(channel_list)
                nexttile
                plot(app.t(1:end), multiple_channel_data(i, 1:end));
                title(sprintf('Electrode %d', electrode_list(i)));
                xlabel('time (s)');
                ylabel('p.d.(mV)');
            end
        end


        function view_all_button(app)
            % Process the data from all channels and plot the outputs in a
            % 4x8 grid in a pop-out window.

            channel_list = app.electrode_to_channel(linspace(1, 32));
            all_channel_data = app.process_signal(channel_list);

            % Plotting
            figure; hold on;
            tiledlayout(8, 4)

            for electrode=0:31
                channel = app.electrode_to_channel(electrode);

                nexttile;
                plot(app.t(1:end), all_channel_data(channel, 1:end));
                title(sprintf('Electrode %d', electrode));
                xlabel('time (s)');
                ylabel('p.d.(mV)');
            end
        end


        function update_reference_electrode(app)
            % Using the value of the reference electrode spinner, and the
            % state of the checkbox next to it, updates the value of the
            % app's attribute 'reference_electrode'. It is set to the
            % user's input if the checkbox is selected, and set to NaN
            % otherwise.

            if app.ReferenceCheckBox.Value
                app.reference_electrode = app.ReferenceelectrodeSpinner.Value;
            else
                app.reference_electrode = NaN;
            end
        end

        %     function reference_channel_data = reference_channel_button (app, channel)
        %
        %             if app.Notch.Value
        %                 reference_channel_data = notch(app.amplifier_data, app.f_notch, app.fs);
        %
        %             elseif app.BandpasslowfqButton.Value
        %                 reference_channel_data = filter_bandpass(app.amplifier_data, app.f1, app.f2, app.fs);
        %
        %             elseif app.NotchBandpasslowfqButton.Value
        %                 reference_channel_data = filter_bandpass(amplifier_data_notch, app.f1, app.f2, app.fs);
        %
        %             elseif app.BandpasshighfqButton.Value
        %                 reference_channel_data = filter_bandpass(app.amplifier_data, app.f3, app.f4, app.fs);
        %
        %             elseif app.NotchBandpasshighfqButton.Value
        %                 reference_channel_data = filter_bandpass(amplifier_data_notch, app.f3, app.f4, app.fs);
        %
        %             end
        %             %plotting
        %
        %
        %              plot(app.t(1:end), difference (channel,1:end));
        %     end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button0
        function button0_pushed(app, event)
            app.single_electrode_button(0)
        end

        % Button pushed function: Button1
        function button1_pushed(app, event)
            app.single_electrode_button(1)
        end

        % Button pushed function: Button2
        function button2_pushed(app, event)
            app.single_electrode_button(2)
        end

        % Button pushed function: Button3
        function button3_pushed(app, event)
            app.single_electrode_button(3)
        end

        % Button pushed function: Button4
        function button4_pushed(app, event)
            app.single_electrode_button(4)
        end

        % Button pushed function: Button5
        function button5_pushed(app, event)
            app.single_electrode_button(5)
        end

        % Button pushed function: Button6
        function button6_pushed(app, event)
            app.single_electrode_button(6)
        end

        % Button pushed function: Button7
        function button7_pushed(app, event)
            app.single_electrode_button(7)
        end

        % Button pushed function: Button8
        function button8_pushed(app, event)
            app.single_electrode_button(8)
        end

        % Button pushed function: Button9
        function button9_pushed(app, event)
            app.single_electrode_button(9)
        end

        % Button pushed function: Button10
        function button10_pushed(app, event)
            app.single_electrode_button(10)
        end

        % Button pushed function: Button11
        function button11_pushed(app, event)
            app.single_electrode_button(11)
        end

        % Button pushed function: Button12
        function button12_pushed(app, event)
            app.single_electrode_button(12)
        end

        % Button pushed function: Button13
        function button13_pushed(app, event)
            app.single_electrode_button(13)
        end

        % Button pushed function: Button14
        function button14_pushed(app, event)
            app.single_electrode_button(14)
        end

        % Button pushed function: Button15
        function button15_pushed(app, event)
            app.single_electrode_button(15)
        end

        % Button pushed function: Button16
        function button16_pushed(app, event)
            app.single_electrode_button(16)
        end

        % Button pushed function: Button17
        function button17_pushed(app, event)
            app.single_electrode_button(17)
        end

        % Button pushed function: Button18
        function button18_pushed(app, event)
            app.single_electrode_button(18)
        end

        % Button pushed function: Button19
        function button19_pushed(app, event)
            app.single_electrode_button(19)
        end

        % Button pushed function: Button20
        function button20_pushed(app, event)
            app.single_electrode_button(20)
        end

        % Button pushed function: Button21
        function button21_pushed(app, event)
            app.single_electrode_button(21)
        end

        % Button pushed function: Button22
        function button22_pushed(app, event)
            app.single_electrode_button(22)
        end

        % Button pushed function: Button23
        function button23_pushed(app, event)
            app.single_electrode_button(23)
        end

        % Button pushed function: Button24
        function button24_pushed(app, event)
            app.single_electrode_button(24)
        end

        % Button pushed function: Button25
        function button25_pushed(app, event)
            app.single_electrode_button(25)
        end

        % Button pushed function: Button26
        function button26_pushed(app, event)
            app.single_electrode_button(26)
        end

        % Button pushed function: Button27
        function button27_pushed(app, event)
            app.single_electrode_button(27)
        end

        % Button pushed function: Button28
        function button28_pushed(app, event)
            app.single_electrode_button(28)
        end

        % Button pushed function: Button29
        function button29_pushed(app, event)
            app.single_electrode_button(29)
        end

        % Button pushed function: Button30
        function button30_pushed(app, event)
            app.single_electrode_button(30)
        end

        % Button pushed function: Button31
        function button31_pushed(app, event)
            app.single_electrode_button(31)
        end

        % Button pushed function: Button0_7
        function button0_7_pushed(app, event)
            app.multiple_electrode_button([0, 1, 2, 3, 4, 5, 6, 7])
        end

        % Button pushed function: Button8_15
        function button8_15_pushed(app, event)
            app.multiple_electrode_button([8, 9, 10, 11, 12, 13, 14, 15])
        end

        % Button pushed function: Button16_23
        function button16_23_pushed(app, event)
            app.multiple_electrode_button([16, 17, 18, 19, 20, 21, 22, 23])
        end

        % Button pushed function: Button24_31
        function button24_31_pushed(app, event)
            app.multiple_electrode_button([24, 25, 26, 27, 28, 29, 30, 31])
        end

        % Button pushed function: ViewallButton
        function viewall_button_pushed(app, event)
            app.view_all_button()
        end

        % Button pushed function: LoadvideofileButton
        function loadvideofile_button_pushed(app, event)
            % Load mp4 video file into the interface.

            [app.filename] = uigetfile('*.mp4', 'Select an .mp4 video File', 'MultiSelect', 'off');

            % @@@ error handling
            % if isequal(app.filename = trye)
            %     disp('No file selected')
            %else
            %     disp('User selected', fullfile(app.filename))
            % end

            app.filename = fullfile(app.filename);
        end

        % Callback function
        function electrodeeditfield_value_changed(app, event)
            
        end

        % Button pushed function: PlayButton
        function play_button_pushed(app, event)
            % Initialises playback of imported video.

            videoReader = VideoReader(app.filename);

            ax = app.UIAxes3;
            fps = app.fpsSpinner.Value;

            % @@@ stop and pause not done
            while hasFrame(videoReader) % && app.stopvideo==false
                %                 get(app.stop_button_pushed, 'on');
                %
                %                 if stop_video
                %                     break;
                %                 end

                % display next frame
                drawnow()
                vidFrame = readFrame(videoReader);
                image(vidFrame, 'Parent', ax);
                set(ax, 'Visible', 'off');

                pause(1/fps);

            end
        end

        % Button pushed function: PauseButton
        function pause_button_pushed(app, event)
            % Set a class attribute to track the state of the button.

            % @@@
        end

        % Button pushed function: StopButton
        function stop_button_pushed(app, event)
            % Set a class attribute to track the state of the button.

            % @@@

            app.stop_button_pushed = 'on';
        end

        % Value changed function: ElectrodeSpinner
        function electrode_spinner_value_changed(app, event)
            % When 'channel' field edited, update value of class attribute.

            electrode = app.ElectrodeSpinner.Value;
            app.video_electrode_button(electrode);
        end

        % Value changed function: ReferenceelectrodeSpinner
        function referenceelectrode_spinner_value_changed(app, event)
            app.update_reference_electrode();
        end

        % Value changed function: ReferenceCheckBox
        function referencecheckbox_value_changed(app, event)
            app.update_reference_electrode();

            if app.ReferenceCheckBox.Value
                app.ReferenceelectrodeSpinner.Enable = 'On';
            else
                app.ReferenceelectrodeSpinner.Enable = 'Off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SignalAnalysisTab = uitab(app.TabGroup);
            app.SignalAnalysisTab.Title = 'Signal Analysis';
            app.Image = uiimage(app.SignalAnalysisTab);
            app.Image.ImageSource = 'Neural interface zoom.png';
            app.Button21 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button21.ButtonPushedFcn = createCallbackFcn(app, @button21_pushed, true);
            app.Button21.Text = '21';
            app.Button22 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button22.ButtonPushedFcn = createCallbackFcn(app, @button22_pushed, true);
            app.Button22.Text = '22';
            app.Button20 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button20.ButtonPushedFcn = createCallbackFcn(app, @button20_pushed, true);
            app.Button20.Text = '20';
            app.Button19 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button19.ButtonPushedFcn = createCallbackFcn(app, @button19_pushed, true);
            app.Button19.Text = '19';
            app.Button23 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button23.ButtonPushedFcn = createCallbackFcn(app, @button23_pushed, true);
            app.Button23.Text = '23';
            app.Button18 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button18.ButtonPushedFcn = createCallbackFcn(app, @button18_pushed, true);
            app.Button18.Text = '18';
            app.Button17 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button17.ButtonPushedFcn = createCallbackFcn(app, @button17_pushed, true);
            app.Button17.Text = '17';
            app.Button16 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button16.ButtonPushedFcn = createCallbackFcn(app, @button16_pushed, true);
            app.Button16.Text = '16';
            app.Button15 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button15.ButtonPushedFcn = createCallbackFcn(app, @button15_pushed, true);
            app.Button15.Text = '15';
            app.Button14 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button14.ButtonPushedFcn = createCallbackFcn(app, @button14_pushed, true);
            app.Button14.Text = '14';
            app.Button13 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button13.ButtonPushedFcn = createCallbackFcn(app, @button13_pushed, true);
            app.Button13.Text = '13';
            app.Button12 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button12.ButtonPushedFcn = createCallbackFcn(app, @button12_pushed, true);
            app.Button12.Text = '12';
            app.Button11 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button11.ButtonPushedFcn = createCallbackFcn(app, @button11_pushed, true);
            app.Button11.Text = '11';
            app.Button10 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button10.ButtonPushedFcn = createCallbackFcn(app, @button10_pushed, true);
            app.Button10.Text = '10';
            app.Button9 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @button9_pushed, true);
            app.Button9.Text = '9';
            app.ModesButtonGroup = uibuttongroup(app.SignalAnalysisTab);
            app.ModesButtonGroup.Title = 'Modes';
            app.Notch = uiradiobutton(app.ModesButtonGroup);
            app.Notch.Text = 'Notch';
            app.Notch.Value = true;
            app.BandpasslowfqButton = uiradiobutton(app.ModesButtonGroup);
            app.BandpasslowfqButton.Text = 'Bandpass (lowfq)';
            app.NotchBandpasslowfqButton = uiradiobutton(app.ModesButtonGroup);
            app.NotchBandpasslowfqButton.Text = 'Notch + Bandpass (lowfq)';
            app.BandpasshighfqButton = uiradiobutton(app.ModesButtonGroup);
            app.BandpasshighfqButton.Text = 'Bandpass (highfq)';
            app.NotchBandpasshighfqButton = uiradiobutton(app.ModesButtonGroup);
            app.NotchBandpasshighfqButton.Text = 'Notch + Bandpass (highfq)';
            app.ReferenceCheckBox = uicheckbox(app.ModesButtonGroup);
            app.ReferenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @referencecheckbox_value_changed, true);
            app.ReferenceCheckBox.Text = '';
            app.Button24_31 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button24_31.ButtonPushedFcn = createCallbackFcn(app, @button24_31_pushed, true);
            app.Button24_31.Text = '24 -31';
            app.Button16_23 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button16_23.ButtonPushedFcn = createCallbackFcn(app, @button16_23_pushed, true);
            app.Button16_23.Text = '16 - 23';
            app.Button8_15 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button8_15.ButtonPushedFcn = createCallbackFcn(app, @button8_15_pushed, true);
            app.Button8_15.Text = '8 - 15';
            app.Button0_7 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button0_7.ButtonPushedFcn = createCallbackFcn(app, @button0_7_pushed, true);
            app.Button0_7.Text = '0 - 7';
            app.ViewallButton = uibutton(app.SignalAnalysisTab, 'push');
            app.ViewallButton.ButtonPushedFcn = createCallbackFcn(app, @viewall_button_pushed, true);
            app.ViewallButton.Text = 'View all';
            app.Button0 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button0.ButtonPushedFcn = createCallbackFcn(app, @button0_pushed, true);
            app.Button0.Text = '0';
            app.Button1 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @button1_pushed, true);
            app.Button1.Text = '1';
            app.Button2 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @button2_pushed, true);
            app.Button2.Text = '2';
            app.Button3 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @button3_pushed, true);
            app.Button3.Text = '3';
            app.Button4 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @button4_pushed, true);
            app.Button4.Text = '4';
            app.Button5 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @button5_pushed, true);
            app.Button5.Text = '5';
            app.Button6 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @button6_pushed, true);
            app.Button6.Text = '6';
            app.Button7 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @button7_pushed, true);
            app.Button7.Text = '7';
            app.Button8 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @button8_pushed, true);
            app.Button8.Text = '8';
            app.Button25 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button25.ButtonPushedFcn = createCallbackFcn(app, @button25_pushed, true);
            app.Button25.Text = '25';
            app.Button24 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button24.ButtonPushedFcn = createCallbackFcn(app, @button24_pushed, true);
            app.Button24.Text = '24';
            app.Button26 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button26.ButtonPushedFcn = createCallbackFcn(app, @button26_pushed, true);
            app.Button26.Text = '26';
            app.Button28 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button28.ButtonPushedFcn = createCallbackFcn(app, @button28_pushed, true);
            app.Button28.Text = '28';
            app.Button27 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button27.ButtonPushedFcn = createCallbackFcn(app, @button27_pushed, true);
            app.Button27.Text = '27';
            app.Button31 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button31.ButtonPushedFcn = createCallbackFcn(app, @button31_pushed, true);
            app.Button31.Text = '31';
            app.Button30 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button30.ButtonPushedFcn = createCallbackFcn(app, @button30_pushed, true);
            app.Button30.Text = '30';
            app.Button29 = uibutton(app.SignalAnalysisTab, 'push');
            app.Button29.ButtonPushedFcn = createCallbackFcn(app, @button29_pushed, true);
            app.Button29.Text = '29';
            app.ReferenceelectrodeSpinnerLabel = uilabel(app.SignalAnalysisTab);
            app.ReferenceelectrodeSpinnerLabel.Text = 'Reference electrode';
            app.ReferenceelectrodeSpinner = uispinner(app.SignalAnalysisTab);
            app.ReferenceelectrodeSpinner.Limits = [0 31];
            app.ReferenceelectrodeSpinner.RoundFractionalValues = 'on';
            app.ReferenceelectrodeSpinner.ValueChangedFcn = createCallbackFcn(app, @referenceelectrode_spinner_value_changed, true);
            app.ReferenceelectrodeSpinner.Enable = 'off';
            app.KinematicanalysisTab = uitab(app.TabGroup);
            app.KinematicanalysisTab.Title = 'Kinematic analysis';
            app.UIAxes3 = uiaxes(app.KinematicanalysisTab);
            app.UIAxes3.XTick = [];
            app.UIAxes3.XTickLabel = '';
            app.UIAxes3.YTick = [];
            app.UIAxes3.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.KinematicanalysisTab);
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.Slider = uislider(app.KinematicanalysisTab);
            app.fpsSpinnerLabel = uilabel(app.KinematicanalysisTab);
            app.fpsSpinnerLabel.Text = 'fps';
            app.fpsSpinner = uispinner(app.KinematicanalysisTab);
            app.fpsSpinner.Limits = [1 120];
            app.fpsSpinner.Value = 120;
            app.StartSpinnerLabel = uilabel(app.KinematicanalysisTab);
            app.StartSpinnerLabel.Text = 'Start ';
            app.StartSpinner = uispinner(app.KinematicanalysisTab);
            app.TimeSpinnerLabel = uilabel(app.KinematicanalysisTab);
            app.TimeSpinnerLabel.Text = 'Time';
            app.TimeSpinner = uispinner(app.KinematicanalysisTab);
            app.LoadvideofileButton = uibutton(app.KinematicanalysisTab, 'push');
            app.LoadvideofileButton.ButtonPushedFcn = createCallbackFcn(app, @loadvideofile_button_pushed, true);
            app.LoadvideofileButton.Text = 'Load video file';
            app.PlayButton = uibutton(app.KinematicanalysisTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @play_button_pushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.KinematicanalysisTab, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @pause_button_pushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.KinematicanalysisTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @stop_button_pushed, true);
            app.StopButton.Text = 'Stop';
            app.ElectrodeSpinnerLabel = uilabel(app.KinematicanalysisTab);
            app.ElectrodeSpinnerLabel.Text = 'Electrode';
            app.ElectrodeSpinner = uispinner(app.KinematicanalysisTab);
            app.ElectrodeSpinner.ValueChangedFcn = createCallbackFcn(app, @electrode_spinner_value_changed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfacedesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end